classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Wire_mesh_adjustment   matlab.ui.Figure
        FileReading            matlab.ui.container.Menu
        createPoint            matlab.ui.container.Menu
        creatLine              matlab.ui.container.Menu
        creatAngle             matlab.ui.container.Menu
        Measureing_Adjustment  matlab.ui.container.Menu
        Menu_4                 matlab.ui.container.Menu
        Menu                   matlab.ui.container.Menu
        map_painting           matlab.ui.container.Menu
        Menu_2                 matlab.ui.container.Menu
        Error_ellipse_drawing  matlab.ui.container.Menu
        Menu_6                 matlab.ui.container.Menu
        Menu_3                 matlab.ui.container.Menu
        Menu_9                 matlab.ui.container.Menu
        TXTMenu                matlab.ui.container.Menu
        XLSMenu                matlab.ui.container.Menu
        helpMenu               matlab.ui.container.Menu
        Menu_7                 matlab.ui.container.Menu
        Menu_8                 matlab.ui.container.Menu
        TabGroup               matlab.ui.container.TabGroup
        Tab_3                  matlab.ui.container.Tab
        Image2                 matlab.ui.control.Image
        Image3                 matlab.ui.control.Image
        Tab                    matlab.ui.container.Tab
        UITable                matlab.ui.control.Table
        Label_4                matlab.ui.control.Label
        UITable_2              matlab.ui.control.Table
        Label_5                matlab.ui.control.Label
        Tab_4                  matlab.ui.container.Tab
        UITable2               matlab.ui.control.Table
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        UITable2_2             matlab.ui.control.Table
        Tab_2                  matlab.ui.container.Tab
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        LineTable=struct;% 观测边表
        
        AngleTable=struct;% 观测角表
        
        PointTable=struct;% 点位表
        
        B;%系数矩阵B
        
        l;%常数矩阵l
        
        P;%权矩阵P
        
        x;%未知参数
        
        mA=5;%角度中误差
        
        max=2;%放大参数
        
        L;%观测值
        
        v;%改正数
        
        Lp;%观测值平差值
        
        sigma0;%单位权中误差
        
        LineResult;%导线边计算结果
        
        AngleResult;%角度计算结果、
        
        PointResult;%导线点计算结果
        
        PointErrorResult;%点位误差结果
        
    end
    
    properties (Access = private)
        
        
    end


    methods (Access = private)
        
        %计算近似方位角
        function Fw(app)
            [beginAngleIndex]=getBeginAngle(app);%找到起算角
            claFw(app,beginAngleIndex);%计算起算角对应的未知方位角的边的方位角
            if not(app.findNoFw) %判断是否有方位角未知的边
                Fw(app);%若还有未知方位角的边，则迭代计算
            end
        end
        
        %计算每条边的detx与dety
        function calDxDy(app)
            len=length(app.LineTable.index);
            for i=1:len
                app.LineTable.ldetX(i)=app.LineTable.lS(i).*cos(app.z2rAngle(app.p2zAngle(app.LineTable.lfw(i))));%计算每条边的DeltaX
                app.LineTable.ldetY(i)=app.LineTable.lS(i).*sin(app.z2rAngle(app.p2zAngle(app.LineTable.lfw(i))));%计算每条边的DeltaY
            end
        end
        %计算近似坐标
        function [app] = CalCoordinate(app)
            [beginLineIndex]=getBeginLine(app);%找到起算边
            calXY(app,beginLineIndex);%计算起算边终点的XY
            if not(findNoUnknowXY(app))%查找是否还有未知终点坐标的边
                CalCoordinate(app);%若有，则迭代计算
            end
        end
        %计算误差方程BLP
        function calBLP(app)
            len=length(app.AngleTable.index);
            for i=1:len
                lfw=app.LineTable.lfw(app.AngleTable.left(i));%左边方位角
                ls=app.LineTable.lS(app.AngleTable.left(i));%左边边长
                lBeginIndex=app.LineTable.lbegin(app.AngleTable.left(i));%左边起始点索引
                lBeginName=app.PointTable.name(app.LineTable.lbegin(app.AngleTable.left(i)));%左边起始点点名
                lEndIndex=app.LineTable.lend(app.AngleTable.left(i));%左边终止点索引
                lEndName=app.PointTable.name(app.LineTable.lend(app.AngleTable.left(i)));%左边终止点点名
                ldetX=app.LineTable.ldetX(app.AngleTable.left(i));%左边deltax
                ldetY=app.LineTable.ldetY(app.AngleTable.left(i));%左边deltay
                rfw=app.LineTable.lfw(app.AngleTable.right(i));
                rs=app.LineTable.lS(app.AngleTable.left(i));
                rBeginIndex=app.LineTable.lbegin(app.AngleTable.right(i));
                rBeginName=app.PointTable.name(app.LineTable.lbegin(app.AngleTable.right(i)));
                rEndIndex=app.LineTable.lend(app.AngleTable.right(i));
                rEndName=app.PointTable.name(app.LineTable.lend(app.AngleTable.right(i)));
                rdetX=app.LineTable.ldetX(app.AngleTable.right(i));
                rdetY=app.LineTable.ldetY(app.AngleTable.right(i));
                temlBeginIndex=lBeginIndex;
                temlEndIndex=lEndIndex;
                temrBeginIndex=rBeginIndex;
                temrEndIndex=rEndIndex;
                rEndName=rEndName{1};
                lEndName=lEndName{1};
                rBeginName=rBeginName{1};
                lBeginName=lBeginName{1};
                %第一种情况 左起点=右起点
                if strcmp(rEndName,lEndName)  %第二种情况 左终点=右终点
                    temlBeginIndex=lEndIndex;
                    temlEndIndex=lBeginIndex;
                    temrBeginIndex=rEndIndex;
                    temrEndIndex=rBeginIndex;
                    ldetX=-ldetX;
                    ldetY=-ldetY;
                    rdetX=-rdetX;
                    rdetY=-rdetY;
                    lfw=lfw+180;
                    rfw=rfw+180;
                end
                if strcmp(lEndName,rBeginName) %第三种情况 左终点=右起点
                    temlBeginIndex=lEndIndex;
                    temlEndIndex=lBeginIndex;
                    temrBeginIndex=rBeginIndex;
                    temrEndIndex=rEndIndex;
                    ldetX=-ldetX;
                    ldetY=-ldetY;
                    lfw=lfw+180;
                end
                if strcmp(lBeginName,rEndName) %第四种情况 左起点=右终点
                    temlBeginIndex=lBeginIndex;
                    temlEndIndex=lEndIndex;
                    temrBeginIndex=rBeginIndex;
                    temrEndIndex=rEndIndex;
                    rdetX=-rdetX;
                    rdetY=-rdetY;
                    rfw=rfw+180;
                end
                app.L(i,1)=(app.AngleTable.angle(i));
                app.B(i,(2*temlEndIndex-1))=206265*ldetY/(1000*(ls^2));
                app.B(i,(2*temlEndIndex))=-206265*ldetX/(1000*(ls^2));
                app.B(i,(2*temrEndIndex-1))=-206265*rdetY/(1000*(rs^2));
                app.B(i,(2*temrEndIndex))=206265*rdetX/(1000*(rs^2));
                app.B(i,(2*temlBeginIndex-1))=206265*rdetY/(1000*(rs^2))-206265*ldetY/(1000*(ls^2));
                app.B(i,(2*temlBeginIndex))=-206265*rdetX/(1000*(rs^2))+206265*ldetX/(1000*(ls^2));
                angle0=app.z2rAngle(app.p2zAngle(rfw))-app.z2rAngle(app.p2zAngle(lfw));
                app.l(i,1)=(app.z2rAngle(app.p2zAngle(app.AngleTable.angle(i)))-angle0);
                app.P(i,i)=1;
                if app.PointTable.isknow(temlBeginIndex)==1
                    app.B(i,(2*temlBeginIndex-1))=0;
                    app.B(i,(2*temlBeginIndex))=0;
                end
                if app.PointTable.isknow(temlEndIndex)==1
                    app.B(i,(2*temlEndIndex-1))=0;
                    app.B(i,(2*temlEndIndex))=0;
                end
                if app.PointTable.isknow(temrEndIndex)==1
                    app.B(i,(2*temrEndIndex-1))=0;
                    app.B(i,(2*temrEndIndex))=0;
                end
            end
            llen=length(app.LineTable.index);
            for i=1:llen
                index=len+i;
                app.L(index,1)=app.LineTable.lS(i);
                beginIndex=app.LineTable.lbegin(i);
                endIndex=app.LineTable.lend(i);
                s0=sqrt(app.LineTable.ldetX(i)^2+app.LineTable.ldetY(i)^2);
                app.B(index,(2*beginIndex-1))=-app.LineTable.ldetX(i)/s0;
                app.B(index,(2*beginIndex))=-app.LineTable.ldetY(i)/s0;
                app.B(index,(2*endIndex-1))=app.LineTable.ldetX(i)/s0;
                app.B(index,(2*endIndex))=app.LineTable.ldetY(i)/s0;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.l(index,1)=1000*(app.LineTable.lS(i)-s0);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %app.P(index,index)=app.LineTable.lS(i)/100;
                app.P(index,index)=str2double(app.mA)^2/app.LineTable.mS(i)^2;
                if app.PointTable.isknow(beginIndex)==1
                    app.B(index,(2*beginIndex-1))=0;
                    app.B(index,(2*beginIndex))=0;
                end
                if app.PointTable.isknow(endIndex)==1
                    app.B(index,(2*endIndex-1))=0;
                    app.B(index,(2*endIndex))=0;
                end
            end
            app.x=pinv(app.B'*app.P*app.B)*(app.B'*app.P*app.l);%广义求逆矩阵pinv
            
        end
        
        %查找起算角 原理：角的左边方位角已知，右边方位角未知，该角可以被当做起算角
        function [beginAngleIndex]=getBeginAngle(app)
            len=length(app.AngleTable.index);
            for i=1:len
                if app.AngleTable.angle(i)~=0
                    if app.LineTable.lfw(app.AngleTable.left(i))~=0 && app.LineTable.lfw(app.AngleTable.right(i))==0
                        beginAngleIndex=i;
                        break;
                    end
                end
            end
        end
        
        %方位角递推
        function claFw(app,beginAngleIndex)
            beginAngle = app.AngleTable.angle(beginAngleIndex);
            beginFw=app.LineTable.lfw(app.AngleTable.left(beginAngleIndex));
            lbegin=app.LineTable.lbegin(app.AngleTable.left(beginAngleIndex));
            lend=app.LineTable.lend(app.AngleTable.left(beginAngleIndex));
            rbegin=app.LineTable.lbegin(app.AngleTable.right(beginAngleIndex));
            rend=app.LineTable.lend(app.AngleTable.right(beginAngleIndex));
            if lbegin==rbegin || lend==rend %情况1 两点的起点或终点一致 右=左+角
                zbeginAngle=app.p2zAngle(beginAngle);
                zbeginFW=app.p2zAngle(beginFw);
                zendFw(1)=zbeginAngle(1)+zbeginFW(1);
                zendFw(2)=zbeginAngle(2)+zbeginFW(2);
                zendFw(3)=zbeginAngle(1)+zbeginFW(3);
                if zendFw(3)>=60
                    zendFw(3)=zendFw(3)-60;
                    zendFw(2)=zendFw(2)+1;
                end
                if zendFw(2)>=60
                    zendFw(2)=zendFw(2)-60;
                    zendFw(1)=zendFw(1)+1;
                end
                if zendFw(1)>360
                    zendFw(1)=zendFw(1)-360;
                end
                app.LineTable.lfw(app.AngleTable.right(beginAngleIndex))=app.z2pAngle(zendFw);
            end
            if lend==rbegin || lbegin==rend %情况2，左终点=右起点or左起点=右终点，右=左+角-180
                zbeginAngle=app.p2zAngle(beginAngle);
                zbeginFW=app.p2zAngle(beginFw);
                zendFw(1)=zbeginAngle(1)+zbeginFW(1);
                zendFw(2)=zbeginAngle(2)+zbeginFW(2);
                zendFw(3)=zbeginAngle(3)+zbeginFW(3);
                zendFw(1)=zendFw(1)-180;
                if zendFw(3)>=60
                    zendFw(3)=zendFw(3)-60;
                    zendFw(2)=zendFw(2)+1;
                end
                if zendFw(2)>=60
                    zendFw(2)=zendFw(2)-60;
                    zendFw(1)=zendFw(1)+1;
                end
                if zendFw(1)>360
                    zendFw(1)=zendFw(1)-360;
                end
                if zendFw(1)<0
                    zendFw(1)=zendFw(1)+360;
                end
                app.LineTable.lfw(app.AngleTable.right(beginAngleIndex))=app.z2pAngle(zendFw);
            end
        end
        
        %判断边表中是否还存在未知方位角的边
        function [judge]=findNoFw(app)
            judge=1;
            len=length(app.LineTable.index);
            for i=1:len
                if app.LineTable.lfw(i)==0
                    judge=0;
                    break;
                end
            end
        end
        
        %查找起算边 原理：边的起始点坐标已知，终止点坐标未知，则可以将该边当做起算边
        function [beginLineIndex]=getBeginLine(app)
            len=length(app.LineTable.index);
            for i=1:len
                if app.PointTable.Xbegin(app.LineTable.lbegin(i))~=0 && app.PointTable.Xbegin(app.LineTable.lend(i))==0
                    beginLineIndex=i;
                    break;
                end
            end
        end
        
        %计算起算边的终点坐标
        function calXY(app,beginLineIndex)
            app.PointTable.Xbegin(app.LineTable.lend(beginLineIndex))=app.PointTable.Xbegin(app.LineTable.lbegin(beginLineIndex))+app.LineTable.ldetX(beginLineIndex);
            app.PointTable.Ybegin(app.LineTable.lend(beginLineIndex))=app.PointTable.Ybegin(app.LineTable.lbegin(beginLineIndex))+app.LineTable.ldetY(beginLineIndex);
        end
        
        %判断点表中是否还存在起始点已知终点未知的点
        function [judge]=findNoUnknowXY(app)
            judge=1;
            len=length(app.LineTable.index);
            for i=1:len
                if app.PointTable.Xbegin(app.LineTable.lend(i))==0 && app.PointTable.Ybegin(app.LineTable.lend(i))==0
                    judge=0;
                    break;
                end
            end
            
        end
        
        %dd.mmss角度转化为数组角度
        function zAngle=p2zAngle(~,pAngle)
            zAngle(1)=floor(pAngle);
            zAngle(2)=floor((pAngle-zAngle(1))*100);
            zAngle(3)=(((pAngle-zAngle(1))*100)-zAngle(2))*100;
        end
        
        %数组角度转化为弧度角度
        function rAngle=z2rAngle(~,zAngle)
            rAngle=deg2rad(zAngle(1)+zAngle(2)/60+zAngle(3)/3600);
        end
        
        %弧度角度转化为数组角度
        function zAngle=r2zAngle(~,rAngle)
            mid = rad2deg(rAngle);
            zAngle(1)=floor(mid);
            second=mid-floor(mid);
            zAngle(2)=0;
            while second>=60
                zAngle(2)=zAngle(2)+1;
                second=second-60;
            end
            zAngle(3)=second;
        end
        
        %数组角度转化为dd.mmss角度
        function pAngle=z2pAngle(~,zAngle)
            pAngle=zAngle(1)+zAngle(2)/100+zAngle(3)/10000;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: creatAngle
        function creatAngleMenuSelected(app, event)
            [filename,filepath]=uigetfile('*.txt','读取观测角文件');
            fid=fopen(strcat(filepath,filename),'rt');
            if(fid==-1)
                msgbox('输入的文件或者路径不正确,无法正确打开观测角文件','警告信息');
                return;
            end
            i=1;
            while 1
                if(feof(fid)==1)
                    break;
                end
                line=fgetl(fid);
                row=strsplit(line);
                app.AngleTable.index(i)=i;
                app.AngleTable.name(i)=(row(1));
                app.AngleTable.left(i)=str2double(row(2));
                app.AngleTable.right(i)=str2double(row(3));
                app.AngleTable.angle(i)=str2double(row(4));
                i=i+1;
            end
            fclose(fid);
        end

        % Menu selected function: creatLine
        function createLineMenuSelected(app, event)
            [filename,filepath]=uigetfile('*.txt','读取观测边文件');
            fid=fopen(strcat(filepath,filename),'rt');
            if(fid==-1)
                msgbox('输入的文件或者路径不正确,无法正确打开观测边文件','警告信息');
                return;
            end
            i=1;
            while 1
                if(feof(fid)==1)
                    break;
                end
                line=fgetl(fid);
                row=strsplit(line);
                app.LineTable.index(i)=i;
                app.LineTable.name(i)=(row(1));
                app.LineTable.lbegin(i)=str2double(row(2));
                app.LineTable.lend(i)=str2double(row(3));
                app.LineTable.lS(i)=str2double(row(4));
                app.LineTable.lfw(i)=str2double(row(5));
                app.LineTable.ldetX(i)=str2double(row(6));
                app.LineTable.ldetY(i)=str2double(row(7));
                app.LineTable.mS(i)=str2double(row(8));
                i=i+1;
            end
            fclose(fid);
        end

        % Menu selected function: createPoint
        function creatPointMenuSelected(app, event)
            [filename,filepath]=uigetfile('*.txt','读取点位文件');
            fid=fopen(strcat(filepath,filename),'rt');
            if(fid==-1)
                msgbox('输入的文件或者路径不正确,无法正确打开观测边文件','警告信息');
                return;
            end
            i=1;
            while 1
                if(feof(fid)==1)
                    break;
                end
                line=fgetl(fid);
                row=strsplit(line);
                app.PointTable.index(i)=i;
                app.PointTable.name(i)=(row(1));
                app.PointTable.isknow(i)=str2double(row(2));
                app.PointTable.Xbegin(i)=str2double(row(3));
                app.PointTable.Ybegin(i)=str2double(row(4));
                app.PointTable.Xadjust(i)=str2double(row(5));
                app.PointTable.Yadjust(i)=str2double(row(6));
                i=i+1;
            end
            fclose(fid);
        end

        % Menu selected function: Menu
        function Measureing_AdjustmentMenuSelected(app, event)
            
            Fw(app);
            calDxDy(app);
            CalCoordinate(app);
            calBLP(app);
            app.v=app.B*app.x-app.l;%计算各个观测值的改正数
            len=length(app.AngleTable.index);
            for i=1:len
                app.v(i)=app.v(i)/10000;
            end
            slen=length(app.LineTable.index);
            for i=1:slen
                app.v(len+i)=app.v(len+i)/100;
            end
            app.Lp=app.L+app.v;%计算观测值平差值
            len=length(app.PointTable.index);
            for i=1:len
                app.PointTable.Xadjust(i)=app.x(2*i-1)/100+app.PointTable.Xbegin(i);
                app.PointTable.Yadjust(i)=app.x(2*i)/100+app.PointTable.Ybegin(i);
            end
            format long g;
            %app.UITable.Visible=true;
            app.UITable.ColumnName={'点名' '纵坐标X/m'  '横坐标Y/m'  'X改正数/cm' 'Y改正数/cm' 'X平差值/m' 'Y平差值/m'};
            for i=1:len
                if i==1
                    app.UITable.Data={app.PointTable.name{i},app.PointTable.Xbegin(i), app.PointTable.Ybegin(i),app.x(2*i-1),app.x(2*i),app.PointTable.Xadjust(i), app.PointTable.Yadjust(i)};
                else
                    t=app.UITable.Data;
                    add={app.PointTable.name{i},app.PointTable.Xbegin(i), app.PointTable.Ybegin(i),app.x(2*i-1),app.x(2*i),app.PointTable.Xadjust(i), app.PointTable.Yadjust(i) };
                    app.UITable.Data=[t;add];
                end
            end
            %             app.UITable2.ColumnName={'角名','观测值/dd.mmss','改正数/"','平差值dd/mmss'};
            %             len=length(app.AngleTable.index);
            %             for i=1:len
            %                 if i==1
            %                     app.UITable2.Data={app.AngleTable.name{i},app.L(i),app.v(i)*10000,app.Lp(i)};
            %                 else
            %                     t=app.UITable2.Data;
            %                     add={app.AngleTable.name{i},app.L(i),app.v(i)*10000,app.Lp(i)};
            %                     app.UITable2.Data=[t;add];
            %                 end
            %             end
            %             app.UITable2_2.ColumnName={'边名','观测值/m','改正数/cm','平差值/m'};
            %             slen=length(app.LineTable.index);
            %             for i=1:slen
            %                 if i==1
            %                     app.UITable2_2.Data={app.LineTable.name{i},app.L(len+i),app.v(len+i)*100,app.Lp(len+i)};
            %                 else
            %                     t=app.UITable2_2.Data;
            %                     add={app.LineTable.name{i},app.L(len+i),app.v(len+i)*100,app.Lp(len+i)};
            %                     app.UITable2_2.Data=[t;add];
            %                 end
            %             end
            n=0;
            t=0;
            len=length(app.PointTable.index);
            for i=1:len
                if app.PointTable.isknow(i)==0
                    t=t+2;
                end
            end
            n=length(app.AngleTable.index)+length(app.LineTable.index);
            r=n-t;
            app.v=app.B*app.x-app.l;
            app.sigma0=sqrt(app.v'*app.P*app.v/r);
            Qxx=pinv(app.B'*app.P*app.B);
            [len,len]=size(Qxx);
            for i = 1:2:len
                qxx = Qxx(i,i);
                qyy = Qxx(i+1,i+1);
                qxy = Qxx(i,i+1);
                H((i+1)/2) = sqrt((qxx-qyy)^2 + 4*qxy^2);
                qee = (qxx+qyy+H((i+1)/2))/2;
                qff = (qxx+qyy-H((i+1)/2))/2;
                E((i+1)/2) = app.sigma0*sqrt(qee)/1;
                F((i+1)/2) = app.sigma0*sqrt(qff)/1;
                sigmax((i+1)/2)=app.sigma0*sqrt(qxx);
                sigmay((i+1)/2)=app.sigma0*sqrt(qyy);
                phie((i+1)/2) = atan2(qee-qff,qxy);
            end
            format short
            app.UITable_2.ColumnName={'点名','X轴误差/cm','Y轴误差/cm','点位误差/cm','极大值/cm','极小值/cm','极大值方向/rad'};
            len=length(app.PointTable.index);
            for i=1:len
                if i==1
                    app.UITable_2.Data={app.PointTable.name{i},sigmax(i)/10,sigmay(i)/10,sqrt(sigmax(i)^2+sigmay(i)^2)/10,E(i)/10,F(i)/10,phie(i)};
                else
                    t=app.UITable_2.Data;
                    add={app.PointTable.name{i},sigmax(i)/10,sigmay(i)/10,sqrt(sigmax(i)^2+sigmay(i)^2)/10,E(i)/10,F(i)/10,phie(i)};
                    app.UITable_2.Data=[t;add];
                end
            end
            %app.UITable.Visible=true;
            %             app.UITable.ColumnName={'点名' '纵坐标X/m'  '横坐标Y/m'  'X改正数/cm' 'Y改正数/cm' 'X平差值/m' 'Y平差值/m'};
            %             for i=1:len
            %                 if i==1
            %                     app.UITable.Data={app.PointTable.name{i},app.PointTable.Xbegin(i) app.PointTable.Ybegin(i) app.x(2*i-1) app.x(2*i) app.PointTable.Xadjust(i) app.PointTable.Yadjust(i) };
            %                 else
            %                     t=app.UITable.Data;
            %                     add={app.PointTable.name{i},app.PointTable.Xbegin(i) app.PointTable.Ybegin(i) app.x(2*i-1) app.x(2*i) app.PointTable.Xadjust(i) app.PointTable.Yadjust(i)};
            %                     app.UITable.Data=[t;add];
            %                 end
            %             end
            format long g
            qll=app.B*(app.B'*app.P*app.B)*app.B';
            app.UITable2.ColumnName={'角名','观测值/dd.mmss','改正数/"','平差值/dd.mmss','精度/"'};
            len=length(app.AngleTable.index);
            for i=1:len
                if i==1
                    app.UITable2.Data={app.AngleTable.name{i},app.L(i),app.v(i),app.Lp(i),app.sigma0*sqrt(qll(i,i))};
                else
                    t=app.UITable2.Data;
                    add={app.AngleTable.name{i},app.L(i),app.v(i),app.Lp(i),app.sigma0*sqrt(qll(i,i))};
                    app.UITable2.Data=[t;add];
                end
            end
            
            app.UITable2_2.ColumnName={'边名','观测值/m','改正数/cm','平差值/m','精度/cm'};
            slen=length(app.LineTable.index);
            for i=1:slen
                if i==1
                    app.UITable2_2.Data={app.LineTable.name{i},app.L(len+i),app.v(len+i),app.Lp(len+i),app.sigma0*sqrt(qll(len+i,len+i))};
                else
                    t=app.UITable2_2.Data;
                    add={app.LineTable.name{i},app.L(len+i),app.v(len+i)/10,app.Lp(len+i),app.sigma0*sqrt(qll(len+i,len+i))/10};
                    app.UITable2_2.Data=[t;add];
                end
            end
            app.LineResult=app.UITable2_2.Data;
            app.AngleResult=app.UITable2.Data;
            app.PointResult=app.UITable.Data;
            app.PointErrorResult=app.UITable_2.Data;
        end

        % Menu selected function: Menu_2
        function map_paintingMenuSelected(app, event)
            len=length(app.LineTable.index);
            for i=1:len
                st = app.LineTable.lbegin(i);
                en = app.LineTable.lend(i);
                plot(app.UIAxes,[app.PointTable.Yadjust(st),app.PointTable.Yadjust(en)],[app.PointTable.Xadjust(st),app.PointTable.Xadjust(en)],'linewidth',0.5,'color','blue');
                hold(app.UIAxes,'on');
            end
            len=length(app.PointTable.index);
            for i=1:len
                text(app.UIAxes,app.PointTable.Yadjust(i),app.PointTable.Xadjust(i),app.PointTable.name(i),'Color','red','FontSize',14);
                hold(app.UIAxes,'on');
                if app.PointTable.isknow(i)==1
                    scatter(app.UIAxes,app.PointTable.Yadjust(i),app.PointTable.Xadjust(i),122,'Marker','p','MarkerFaceColor','red');
                    hold(app.UIAxes,'on');
                else
                    scatter(app.UIAxes,app.PointTable.Yadjust(i),app.PointTable.Xadjust(i),66,'Marker','o','MarkerFaceColor','red');
                    hold(app.UIAxes,'on');
                end
            end
            %app.UIAxes.Visible=true;
        end

        % Menu selected function: Menu_3
        function Error_ellipse_drawingMenuSelected(app, event)
            n=0;
            t=0;
            len=length(app.PointTable.index);
            for i=1:len
                if app.PointTable.isknow(i)==0
                    t=t+2;
                end
            end
            n=length(app.AngleTable.index)+length(app.LineTable.index);
            r=n-t;
            app.v=app.B*app.x-app.l;
            app.sigma0=sqrt(app.v'*app.P*app.v/r);
            Qxx=pinv(app.B'*app.P*app.B);
            [len,len]=size(Qxx);
            for i = 1:2:len
                qxx = Qxx(i,i);
                qyy = Qxx(i+1,i+1);
                qxy = Qxx(i,i+1);
                H((i+1)/2) = sqrt((qxx-qyy)^2 + 4*qxy^2);
                qee = (qxx+qyy+H((i+1)/2))/2;
                qff = (qxx+qyy-H((i+1)/2))/2;
                E((i+1)/2) = app.sigma0*sqrt(qee)/1;
                F((i+1)/2) = app.sigma0*sqrt(qff)/1;
                phie((i+1)/2) = atan2(qee-qff,qxy);
            end
            len=length(app.PointTable.index);
            for i=1:len
                circle = 0:0.01:2*pi;
                xout = app.PointTable.Xadjust(i) + str2double(app.max)*E(i)*cos(phie(i))*cos(circle) - str2double(app.max)*F(i)*sin(phie(i))*sin(circle);
                yout = app.PointTable.Yadjust(i) + str2double(app.max)*E(i)*sin(phie(i))*cos(circle) + str2double(app.max)*F(i)*cos(phie(i))*sin(circle);
                plot(app.UIAxes,yout,xout,'-b');
                hold(app.UIAxes,'on');
            end
            
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            app.mA=inputdlg("请输入测角中误差");
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            app.max=inputdlg("请输入放大参数");
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            msgbox('QQ：2696690420   微信：18353802918','联系作者');
        end

        % Menu selected function: TXTMenu
        function TXTMenuSelected(app, event)
            %将运算结果以TXT文本格式输出
            rowpointname={'点名' '纵坐标X/m'  '横坐标Y/m'  'X改正数/cm' 'Y改正数/cm' 'X平差值/m' 'Y平差值/m'};
            writetable(table([rowpointname;app.PointResult]),'Result\导线点计算结果.txt','Delimiter',' ',"WriteVariableNames",false);
            rowpointerrorname={'点名','X轴误差/cm','Y轴误差/cm','点位误差/cm','极大值/cm','极小值/cm','极大值方向/rad'};
            writetable(table([rowpointerrorname;app.PointErrorResult]),'Result\导线点位误差计算结果.txt','Delimiter',' ',"WriteVariableNames",false);
            rowlinename={'边名','观测值/m','改正数/cm','平差值/m','精度/cm'};
            writetable(table([rowlinename;app.LineResult]),'Result\观测边计算结果.txt','Delimiter',' ',"WriteVariableNames",false);
            rowanglename={'角名','观测值/dd.mmss','改正数/"','平差值/dd.mmss','精度/"'};
            writetable(table([rowanglename;app.AngleResult]),'Result\观测角误差计算结果.txt','Delimiter',' ',"WriteVariableNames",false);
            msgbox('文件输入成功，请到程序路径下查看');
        end

        % Menu selected function: XLSMenu
        function XLSMenuSelected(app, event)
            %将运算结果以XLS格式输出
            rowpointname={'点名' '纵坐标X/m'  '横坐标Y/m'  'X改正数/cm' 'Y改正数/cm' 'X平差值/m' 'Y平差值/m'};
            writetable(table([rowpointname;app.PointResult]),'Result\导线点计算结果.xls',"WriteVariableNames",false);
            rowpointerrorname={'点名','X轴误差/cm','Y轴误差/cm','点位误差/cm','极大值/cm','极小值/cm','极大值方向/rad'};
            writetable(table([rowpointerrorname;app.PointErrorResult]),'Result\导线点位误差计算结果.xls',"WriteVariableNames",false);
            rowlinename={'边名','观测值/m','改正数/cm','平差值/m','精度/cm'};
            writetable(table([rowlinename;app.LineResult]),'Result\观测边计算结果.xls',"WriteVariableNames",false);
            rowanglename={'角名','观测值/dd.mmss','改正数/"','平差值/dd.mmss','精度/"'};
            writetable(table([rowanglename;app.AngleResult]),'Result\观测角误差计算结果.xls',"WriteVariableNames",false);
            msgbox('文件输入成功，请到程序路径下查看');
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            open('帮助文档.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Wire_mesh_adjustment = uifigure('Visible', 'off');
            app.Wire_mesh_adjustment.Name = '一键平(导线网平差)';
            app.FileReading = uimenu(app.Wire_mesh_adjustment);
            app.FileReading.Text = '文件读取';
            app.createPoint = uimenu(app.FileReading);
            app.createPoint.MenuSelectedFcn = createCallbackFcn(app, @creatPointMenuSelected, true);
            app.createPoint.Text = '导线点';
            app.creatLine = uimenu(app.FileReading);
            app.creatLine.MenuSelectedFcn = createCallbackFcn(app, @createLineMenuSelected, true);
            app.creatLine.Text = '导线边';
            app.creatAngle = uimenu(app.FileReading);
            app.creatAngle.MenuSelectedFcn = createCallbackFcn(app, @creatAngleMenuSelected, true);
            app.creatAngle.Text = '角度';
            app.Measureing_Adjustment = uimenu(app.Wire_mesh_adjustment);
            app.Measureing_Adjustment.Text = '导线网平差';
            app.Menu_4 = uimenu(app.Measureing_Adjustment);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '输入角度误差';
            app.Menu = uimenu(app.Measureing_Adjustment);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @Measureing_AdjustmentMenuSelected, true);
            app.Menu.Text = '平差';
            app.map_painting = uimenu(app.Wire_mesh_adjustment);
            app.map_painting.Text = '导线略图绘制';
            app.Menu_2 = uimenu(app.map_painting);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @map_paintingMenuSelected, true);
            app.Menu_2.Text = '开始略图绘制';
            app.Error_ellipse_drawing = uimenu(app.Wire_mesh_adjustment);
            app.Error_ellipse_drawing.Text = '误差椭圆绘制';
            app.Menu_6 = uimenu(app.Error_ellipse_drawing);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '输入误差椭圆放大参数(若不输入则为默认值）';
            app.Menu_3 = uimenu(app.Error_ellipse_drawing);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Error_ellipse_drawingMenuSelected, true);
            app.Menu_3.Text = '开始椭圆绘制';
            app.Menu_9 = uimenu(app.Wire_mesh_adjustment);
            app.Menu_9.Text = '数据导出';
            app.TXTMenu = uimenu(app.Menu_9);
            app.TXTMenu.MenuSelectedFcn = createCallbackFcn(app, @TXTMenuSelected, true);
            app.TXTMenu.Text = '导出为TXT格式';
            app.XLSMenu = uimenu(app.Menu_9);
            app.XLSMenu.MenuSelectedFcn = createCallbackFcn(app, @XLSMenuSelected, true);
            app.XLSMenu.Text = '导出为XLS格式';
            app.helpMenu = uimenu(app.Wire_mesh_adjustment);
            app.helpMenu.Text = 'help';
            app.Menu_7 = uimenu(app.helpMenu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '打开帮助文档';
            app.Menu_8 = uimenu(app.helpMenu);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '联系作者';
            app.TabGroup = uitabgroup(app.Wire_mesh_adjustment);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '主页';
            app.Image2 = uiimage(app.Tab_3);
            app.Image2.ImageSource = 'ok.png';
            app.Image3 = uiimage(app.Tab_3);
            app.Image3.ImageSource = 'bt.png';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '坐标平差表';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'点名'; '纵坐标X/m'; '横坐标Y/m'; 'X改正数/cm'; 'Y改正数/cm'; 'X平差值/m'; 'Y平差值/m'};
            app.UITable.RowName = {};
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '坐标平差值';
            app.UITable_2 = uitable(app.Tab);
            app.UITable_2.ColumnName = {'点名'; 'X轴误差/cm'; 'Y轴误差/cm'; '点位误差/cm'; '极大值/cm'; '极小值/cm'; '极大值方向/rad'};
            app.UITable_2.RowName = {};
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '点位误差与误差椭圆参数';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '边长角度平差表';
            app.UITable2 = uitable(app.Tab_4);
            app.UITable2.ColumnName = {'角名'; '观测值/dd.mmss'; '改正数/"'; '平差值/dd.mmss'; '精度/dd.mmss'};
            app.UITable2.RowName = {};
            app.Label_2 = uilabel(app.Tab_4);
            app.Label_2.Text = '角度平差表';
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = '边长平差表';
            app.UITable2_2 = uitable(app.Tab_4);
            app.UITable2_2.ColumnName = {'边名'; '观测值/m'; '改正数/cm'; '平差值/m'; '精度/cm'};
            app.UITable2_2.RowName = {};
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '导线图';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, '导线图')
            xlabel(app.UIAxes, 'Y')
            ylabel(app.UIAxes, 'X')
            % Show the figure after all components are created
            app.Wire_mesh_adjustment.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Wire_mesh_adjustment)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Wire_mesh_adjustment)
        end
    end
end